classdef IQ_Slice_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SliceindexSlider           matlab.ui.control.Slider
        SliceindexSliderLabel      matlab.ui.control.Label
        SliceParameterButtonGroup  matlab.ui.container.ButtonGroup
        DC3Button                  matlab.ui.control.RadioButton
        DC2Button                  matlab.ui.control.RadioButton
        DC1Button                  matlab.ui.control.RadioButton
        UIAxes_2f                  matlab.ui.control.UIAxes
        UIAxes_Q                   matlab.ui.control.UIAxes
        UIAxes_I                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        V1 % Description
        V2 % Description
        V3 % Description
        data % Description
    end
    
    methods (Access = private)
        
        function results = initial_display(app)
            surf(app.UIAxes_I,app.V1,app.V2,app.data(:,:,1,1));
            shading flat;
            surf(app.UIAxes_Q,app.V1,app.V2,app.data(:,:,1,2));
            shading flat;
            surf(app.UIAxes_2f,app.V1,app.V2,app.data(:,:,1,3));
            shading flat;
        end
        
        function results = display_data(app,value)
            if nargin == 1
                value = app.SliceindexSlider.Value;
            end
            vI = get(app.UIAxes_I,'view');
            vQ = get(app.UIAxes_Q,'view');
            v2f = get(app.UIAxes_2f,'view');
            if app.DC1Button.Value
                
                x = app.V2;
                y = app.V3;
                idx = find(app.V1 >= value,1,'first');
                d.I = squeeze(app.data(idx,:,:,1));
                d.Q = squeeze(app.data(idx,:,:,2));
                d.f2 = squeeze(app.data(idx,:,:,3));
                labels = {'V2','V3'};
            elseif app.DC2Button.Value
                x = app.V1;
                y = app.V3;
                idx = find(app.V2>= value,1,'first');
                d.I = squeeze(app.data(:,idx,:,1));
                d.Q = squeeze(app.data(:,idx,:,2));
                d.f2 = squeeze(app.data(:,idx,:,3));
                labels = {'V1','V3'};
            else
                x = app.V1;
                y = app.V2;
                idx = find(app.V3 >= value,1,'first');
                d.I = squeeze(app.data(:,:,idx,1));
                d.Q = squeeze(app.data(:,:,idx,2));
                d.f2 = squeeze(app.data(:,:,idx,3));
                labels = {'V1','V2'};
            end
            surf(app.UIAxes_I,x,y,d.I);shading flat;
            set(app.UIAxes_I,'view',vI);
            app.UIAxes_I.XAxis.Label.String = labels{2};
            app.UIAxes_I.YAxis.Label.String = labels{1};
            surf(app.UIAxes_Q,x,y,d.Q);shading flat;
            set(app.UIAxes_Q,'view',vQ);
            app.UIAxes_Q.XAxis.Label.String = labels{2};
            app.UIAxes_Q.YAxis.Label.String = labels{1};
            surf(app.UIAxes_2f,x,y,d.f2);shading flat;
            set(app.UIAxes_2f,'view',v2f);
            app.UIAxes_2f.XAxis.Label.String = labels{2};
            app.UIAxes_2f.YAxis.Label.String = labels{1};
            drawnow;
        end
        
        function results = update_slice_labels(app)
            if app.DC1Button.Value
                app.SliceindexSlider.Limits = [min(app.V1),max(app.V1)];
            elseif app.DC2Button.Value
                app.SliceindexSlider.Limits = [min(app.V2),max(app.V2)];
            else
                app.SliceindexSlider.Limits = [min(app.V3),max(app.V3)];
            end
            app.SliceindexSlider.MajorTicks = linspace(app.SliceindexSlider.Limits(1),app.SliceindexSlider.Limits(2),10);
        end
        
    end
    
    methods (Access = public)
        
        function results = set_data(app,data,V1,V2,V3)
            app.data = data;
            app.V1 = 1:size(app.data,1);
            app.V2 = 1:size(app.data,2);
            app.V3 = 1:size(app.data,3);
            if nargin == 3
                app.V1 = V1;
                app.V2 = V1;
                app.V3 = V1;
            end
            if nargin == 4
                app.V2 = V2;
                app.V3 = V2;
            end
            if nargin == 5
                app.V3 = V3;
            end
            app.DC1Button.Value = 0;
            app.DC2Button.Value = 0;
            app.DC3Button.Value = 1;
            app.update_slice_labels;
            app.initial_display;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, V1, V2, V3)
            app.data = data;
            app.V1 = 1:size(app.data,1);
            app.V2 = 1:size(app.data,2);
            app.V3 = 1:size(app.data,3);
            if nargin == 3
                app.V1 = V1;
                app.V2 = V1;
                app.V3 = V1;
            end
            if nargin == 4
                app.V2 = V2;
                app.V3 = V2;
            end
            if nargin == 5
                app.V3 = V3;
            end
            app.DC1Button.Value = 0;
            app.DC2Button.Value = 0;
            app.DC3Button.Value = 1;
            app.SliceindexSlider.Limits = [min(app.V3),max(app.V3)];
            app.SliceindexSlider.Value = app.SliceindexSlider.Limits(1);
            app.initial_display;
            
        end

        % Value changed function: SliceindexSlider
        function SliceindexSliderValueChanged(app, event)
            value = app.SliceindexSlider.Value;
            app.display_data;
        end

        % Selection changed function: SliceParameterButtonGroup
        function SliceParameterButtonGroupSelectionChanged(app, event)
            selectedButton = app.SliceParameterButtonGroup.SelectedObject;
            app.update_slice_labels;
            app.display_data;
        end

        % Value changing function: SliceindexSlider
        function SliceindexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.display_data(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_I = uiaxes(app.UIFigure);
            title(app.UIAxes_I, 'I component')
            xlabel(app.UIAxes_I, 'X')
            ylabel(app.UIAxes_I, 'Y')
            zlabel(app.UIAxes_I, 'Z')
            app.UIAxes_Q = uiaxes(app.UIFigure);
            title(app.UIAxes_Q, 'Q component')
            xlabel(app.UIAxes_Q, 'X')
            ylabel(app.UIAxes_Q, 'Y')
            zlabel(app.UIAxes_Q, 'Z')
            app.UIAxes_2f = uiaxes(app.UIFigure);
            title(app.UIAxes_2f, '2f component')
            xlabel(app.UIAxes_2f, 'X')
            ylabel(app.UIAxes_2f, 'Y')
            zlabel(app.UIAxes_2f, 'Z')
            app.SliceParameterButtonGroup = uibuttongroup(app.UIFigure);
            app.SliceParameterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SliceParameterButtonGroupSelectionChanged, true);
            app.SliceParameterButtonGroup.Title = 'Slice Parameter';
            app.DC1Button = uiradiobutton(app.SliceParameterButtonGroup);
            app.DC1Button.Text = 'DC 1';
            app.DC1Button.Value = true;
            app.DC2Button = uiradiobutton(app.SliceParameterButtonGroup);
            app.DC2Button.Text = 'DC 2';
            app.DC3Button = uiradiobutton(app.SliceParameterButtonGroup);
            app.DC3Button.Text = 'DC 3';
            app.SliceindexSliderLabel = uilabel(app.UIFigure);
            app.SliceindexSliderLabel.Text = 'Slice index';
            app.SliceindexSlider = uislider(app.UIFigure);
            app.SliceindexSlider.ValueChangedFcn = createCallbackFcn(app, @SliceindexSliderValueChanged, true);
            app.SliceindexSlider.ValueChangingFcn = createCallbackFcn(app, @SliceindexSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IQ_Slice_Viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end