classdef IQ_Bias_Control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IQBiasControlUIFigure           matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        Log2CICRateSpinnerLabel         matlab.ui.control.Label
        Log2CICRateSpinner              matlab.ui.control.Spinner
        dtsEditFieldLabel               matlab.ui.control.Label
        dtsEditField                    matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        ModulationFreqMHzEditFieldLabel  matlab.ui.control.Label
        ModulationFreqMHzEditField      matlab.ui.control.NumericEditField
        OutputScaleEditFieldLabel       matlab.ui.control.Label
        OutputScaleEditField            matlab.ui.control.NumericEditField
        DemodPhase1degEditFieldLabel    matlab.ui.control.Label
        DemodPhase1degEditField         matlab.ui.control.NumericEditField
        DemodPhase2degEditFieldLabel    matlab.ui.control.Label
        DemodPhase2degEditField         matlab.ui.control.NumericEditField
        Log2ofCICshiftSpinnerLabel      matlab.ui.control.Label
        Log2ofCICshiftSpinner           matlab.ui.control.Spinner
        ApplicationSettingsPanel        matlab.ui.container.Panel
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        AutoUpdateSwitch                matlab.ui.control.Switch
        UploadButton                    matlab.ui.control.Button
        FetchButton                     matlab.ui.control.Button
        AutoFetchDataSwitchLabel        matlab.ui.control.Label
        AutoFetchDataSwitch             matlab.ui.control.Switch
        FetchDataButton                 matlab.ui.control.Button
        PlotUpdateTimemsEditFieldLabel  matlab.ui.control.Label
        PlotUpdateTimemsEditField       matlab.ui.control.NumericEditField
        YLim1Label                      matlab.ui.control.Label
        YLim1EditField                  matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        AcquisitionSamplesEditFieldLabel  matlab.ui.control.Label
        AcquisitionSamplesEditField     matlab.ui.control.NumericEditField
        YLim2EditFieldLabel             matlab.ui.control.Label
        YLim2EditField                  matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        PID1Tab                         matlab.ui.container.Tab
        RealKpEditField                 matlab.ui.control.NumericEditField
        RealKiEditField                 matlab.ui.control.NumericEditField
        RealKdEditField                 matlab.ui.control.NumericEditField
        RealKiLabel                     matlab.ui.control.Label
        ReadKdLabel                     matlab.ui.control.Label
        EnableSwitchLabel               matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        PolaritySwitchLabel             matlab.ui.control.Label
        PolaritySwitch                  matlab.ui.control.Switch
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        SetPointEditField_2Label        matlab.ui.control.Label
        SetPointEditField               matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        KpSpinnerLabel                  matlab.ui.control.Label
        KpSpinner                       matlab.ui.control.Spinner
        KiSpinnerLabel                  matlab.ui.control.Label
        KiSpinner                       matlab.ui.control.Spinner
        KdSpinnerLabel                  matlab.ui.control.Label
        KdSpinner                       matlab.ui.control.Spinner
        DivisorSpinnerLabel             matlab.ui.control.Label
        DivisorSpinner                  matlab.ui.control.Spinner
        PID2Tab                         matlab.ui.container.Tab
        RealKpEditField_2               matlab.ui.control.NumericEditField
        RealKiEditField_2               matlab.ui.control.NumericEditField
        RealKdEditField_2               matlab.ui.control.NumericEditField
        RealKiLabel_2                   matlab.ui.control.Label
        RealKdLabel_2                   matlab.ui.control.Label
        EnableSwitch_2Label             matlab.ui.control.Label
        EnableSwitch_2                  matlab.ui.control.Switch
        PolaritySwitch_2Label           matlab.ui.control.Label
        PolaritySwitch_2                matlab.ui.control.Switch
        UpperLimitEditField_2Label      matlab.ui.control.Label
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        SetPointEditField_3Label        matlab.ui.control.Label
        SetPointEditField_2             matlab.ui.control.NumericEditField
        LowerLimitEditField_2Label      matlab.ui.control.Label
        LowerLimitEditField_2           matlab.ui.control.NumericEditField
        KpSpinner_2Label                matlab.ui.control.Label
        KpSpinner_2                     matlab.ui.control.Spinner
        KiSpinner_2Label                matlab.ui.control.Label
        KiSpinner_2                     matlab.ui.control.Spinner
        KdSpinner_2Label                matlab.ui.control.Label
        KdSpinner_2                     matlab.ui.control.Spinner
        DivisorSpinner_2Label           matlab.ui.control.Label
        DivisorSpinner_2                matlab.ui.control.Spinner
        PID3Tab                         matlab.ui.container.Tab
        RealKpEditField_3               matlab.ui.control.NumericEditField
        RealKiEditField_3               matlab.ui.control.NumericEditField
        RealKdEditField_3               matlab.ui.control.NumericEditField
        RealKiLabel_3                   matlab.ui.control.Label
        RealKdLabel_3                   matlab.ui.control.Label
        EnableSwitch_3Label             matlab.ui.control.Label
        EnableSwitch_3                  matlab.ui.control.Switch
        PolaritySwitch_3Label           matlab.ui.control.Label
        PolaritySwitch_3                matlab.ui.control.Switch
        UpperLimitEditField_3Label      matlab.ui.control.Label
        UpperLimitEditField_3           matlab.ui.control.NumericEditField
        SetPointEditField_3Label_2      matlab.ui.control.Label
        SetPointEditField_3             matlab.ui.control.NumericEditField
        LowerLimitEditField_3Label      matlab.ui.control.Label
        LowerLimitEditField_3           matlab.ui.control.NumericEditField
        KpSpinner_3Label                matlab.ui.control.Label
        KpSpinner_3                     matlab.ui.control.Spinner
        KiSpinner_3Label                matlab.ui.control.Label
        KiSpinner_3                     matlab.ui.control.Spinner
        KdSpinner_3Label                matlab.ui.control.Label
        KdSpinner_3                     matlab.ui.control.Spinner
        DivisorSpinner_3Label           matlab.ui.control.Label
        DivisorSpinner_3                matlab.ui.control.Spinner
        ManualControlPanel              matlab.ui.container.Panel
        SliderLabel                     matlab.ui.control.Label
        DC1Slider                       matlab.ui.control.Slider
        DC1SpinnerLabel                 matlab.ui.control.Label
        DC1Spinner                      matlab.ui.control.Spinner
        SliderLabel_2                   matlab.ui.control.Label
        DC2Slider                       matlab.ui.control.Slider
        DC2SpinnerLabel                 matlab.ui.control.Label
        DC2Spinner                      matlab.ui.control.Spinner
        SliderLabel_3                   matlab.ui.control.Label
        DC3Slider                       matlab.ui.control.Slider
        DC3SpinnerLabel                 matlab.ui.control.Label
        DC3Spinner                      matlab.ui.control.Spinner
        DCIncrementEditFieldLabel       matlab.ui.control.Label
        DCIncrementEditField            matlab.ui.control.NumericEditField
        FIFOSettingsPanel               matlab.ui.container.Panel
        FIFO1DropDownLabel              matlab.ui.control.Label
        FIFO1DropDown                   matlab.ui.control.DropDown
        FIFO2DropDownLabel              matlab.ui.control.Label
        FIFO2DropDown                   matlab.ui.control.DropDown
        FIFO3DropDownLabel              matlab.ui.control.Label
        FIFO3DropDown                   matlab.ui.control.DropDown
        FIFO4DropDownLabel              matlab.ui.control.Label
        FIFO4DropDown                   matlab.ui.control.DropDown
        FIFO1CheckBox                   matlab.ui.control.CheckBox
        FIFO2CheckBox                   matlab.ui.control.CheckBox
        FIFO3CheckBox                   matlab.ui.control.CheckBox
        FIFO4CheckBox                   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        device                           DeviceControl % DeviceControl control
        autoUpdate % Description
        int_timer % Description
        plotType % Description
    end
    
    methods (Access = private)
        
        function results = updateDevice(app)
            if strcmpi(app.AutoUpdateSwitch.Value,'On') && strcmpi(app.AutoFetchDataSwitch.Value,'Off')
                app.device.upload;
            end
            app.updateDisplay;
        end
        
        function results = updateDisplay(app)
            %
            % Shorten names
            %
            d = app.device;
            pid = d.pid;
            %
            % Main settings
            %
            app.ModulationFreqMHzEditField.Value = d.phase_inc.value/1e6;
            app.OutputScaleEditField.Value = d.output_scale.value;
            app.DemodPhase1degEditField.Value = d.phase_offset.value;
            app.DemodPhase2degEditField.Value = d.dds2_phase_offset.value;
            app.Log2CICRateSpinner.Value = d.log2_rate.value;
            app.Log2ofCICshiftSpinner.Value = d.cic_shift.value;
            app.dtsEditField.Value = d.dt();
            app.BWHzEditField.Value = 1/(2*d.dt());
            %
            % PWM settings
            %
            app.DC1Spinner.Value = d.pwm(1).value;
            app.DC1Slider.Value = d.pwm(1).value;
            app.DC2Spinner.Value = d.pwm(2).value;
            app.DC2Slider.Value = d.pwm(2).value;
            app.DC3Spinner.Value = d.pwm(3).value;
            app.DC3Slider.Value = d.pwm(3).value;
            %
            % PID1 settings
            %
            if pid(1).enable.value
                app.EnableSwitch.Value = 'On';
            else
                app.EnableSwitch.Value = 'Off';
            end
            if pid(1).polarity.value == 0
                app.PolaritySwitch.Value = 'Negative';
            else
                app.PolaritySwitch.Value = 'Positive';
            end
            app.KpSpinner.Value = pid(1).Kp.value;
            app.KiSpinner.Value = pid(1).Ki.value;
            app.KdSpinner.Value = pid(1).Kd.value;
            app.DivisorSpinner.Value = pid(1).divisor.value;
            app.UpperLimitEditField.Value = pid(1).upper_limit.value;
            app.LowerLimitEditField.Value = pid(1).lower_limit.value;
            app.SetPointEditField.Value = pid(1).control.value;
            [Kp,Ki,Kd] = pid(1).calculateRealGains;
            app.RealKpEditField.Value = Kp;
            app.RealKiEditField.Value = Ki;
            app.RealKdEditField.Value = Kd;
            %
            % PID2 settings
            %
            if pid(2).enable.value
                app.EnableSwitch_2.Value = 'On';
            else
                app.EnableSwitch_2.Value = 'Off';
            end
            if pid(2).polarity.value == 0
                app.PolaritySwitch_2.Value = 'Negative';
            else
                app.PolaritySwitch_2.Value = 'Positive';
            end
            app.KpSpinner_2.Value = pid(2).Kp.value;
            app.KiSpinner_2.Value = pid(2).Ki.value;
            app.KdSpinner_2.Value = pid(2).Kd.value;
            app.DivisorSpinner_2.Value = pid(2).divisor.value;
            app.UpperLimitEditField_2.Value = pid(2).upper_limit.value;
            app.LowerLimitEditField_2.Value = pid(2).lower_limit.value;
            app.SetPointEditField_2.Value = pid(2).control.value;
            [Kp,Ki,Kd] = pid(2).calculateRealGains;
            app.RealKpEditField_2.Value = Kp;
            app.RealKiEditField_2.Value = Ki;
            app.RealKdEditField_2.Value = Kd;
            %
            % PID3 settings
            %
            if pid(3).enable.value
                app.EnableSwitch_3.Value = 'On';
            else
                app.EnableSwitch_3.Value = 'Off';
            end
            if pid(3).polarity.value == 0
                app.PolaritySwitch_3.Value = 'Negative';
            else
                app.PolaritySwitch_3.Value = 'Positive';
            end
            app.KpSpinner_3.Value = pid(3).Kp.value;
            app.KiSpinner_3.Value = pid(3).Ki.value;
            app.KdSpinner_3.Value = pid(3).Kd.value;
            app.DivisorSpinner_3.Value = pid(3).divisor.value;
            app.UpperLimitEditField_3.Value = pid(3).upper_limit.value;
            app.LowerLimitEditField_3.Value = pid(3).lower_limit.value;
            app.SetPointEditField_3.Value = pid(3).control.value;
            [Kp,Ki,Kd] = pid(3).calculateRealGains;
            app.RealKpEditField_3.Value = Kp;
            app.RealKiEditField_3.Value = Ki;
            app.RealKdEditField_3.Value = Kd;
            %
            % FIFO routing table
            %
            app.FIFO1DropDown.Value = app.convert_fifo_route(d.fifo_route(1).value);
            app.FIFO2DropDown.Value = app.convert_fifo_route(d.fifo_route(2).value);
            app.FIFO3DropDown.Value = app.convert_fifo_route(d.fifo_route(3).value);
            app.FIFO4DropDown.Value = app.convert_fifo_route(d.fifo_route(4).value);
        end
        
        function results = updatePlot(app)
            app.device.upload;
            app.device.getDemodulatedData(app.AcquisitionSamplesEditField.Value);
            yyaxis(app.UIAxes,'left');
            cla(app.UIAxes);
            yyaxis(app.UIAxes,'right');
            cla(app.UIAxes);
            clr = {'#0072BD','#D95319','#EDB120','#7E2F8E'};
            for nn = 1:size(app.device.data,2)
                s = sprintf('FIFO%dCheckBox',nn);
                s2 = sprintf('FIFO%dDropDown',nn);
                if app.(s).Value
                    if ~app.convert_fifo_route(app.(s2).Value)
                        yyaxis(app.UIAxes,'left');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn),'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    else
                        yyaxis(app.UIAxes,'right');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn)*app.device.CONV_PWM,'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    end
                end
            end
            yyaxis(app.UIAxes,'left');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [arb. units]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims1 = sscanf(app.YLim1EditField.Value,'%f,%f');
            if ~isempty(lims1)
                try
                    set(app.UIAxes,'YLim',sort(lims1));
                catch
                    
                end
            end
            
            yyaxis(app.UIAxes,'right');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [V]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims2 = sscanf(app.YLim2EditField.Value,'%f,%f');
            if ~isempty(lims2)
                try
                    set(app.UIAxes,'YLim',sort(lims2));
                catch
                    
                end
            end
        end
        
        
        function results = convert_fifo_route(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'In';
                else
                    results = 'Out';
                end
            else
                if strcmpi(v,'In')
                    results = 0;
                elseif strcmpi(v,'Out')
                    results = 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                app.device = DeviceControl;
            elseif isa(varargin{1},'DeviceControl')
                app.device = varargin{1};
            elseif ischar(varargin{1}) || isstring(varargin{1})
                if numel(varargin) == 1
                    app.device = DeviceControl(varargin{1});
                else
                    app.device = DeviceControl(varargin{1},varargin{2});
                end
                app.device.setDefaults;
            else
                error('Input arguments must be either a ''DeviceControl'' object or an IP address and port number!');
            end
            
            if nargin == 2 && ~isa(varargin{1},'Device')
                try
                    app.device.conn.open;
                    app.device.fetch;
                catch ME
                    warning('A connection to the socket server could not be established!');
                end
            end
            app.updateDisplay;
            warning('off','MATLAB:callback:error');
            
        end

        % Value changed function: Log2CICRateSpinner
        function Log2CICRateSpinnerValueChanged(app, event)
            value = app.Log2CICRateSpinner.Value;
            app.device.log2_rate.set(value);
            app.updateDevice;
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                 value = 1;
            end
           app.device.pid(1).enable.set(value);
           app.updateDevice;
        end

        % Value changed function: PolaritySwitch
        function PolaritySwitchValueChanged(app, event)
            value = app.PolaritySwitch.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.device.pid(1).polarity.set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            value = app.UpperLimitEditField.Value;
            app.device.pid(1).upper_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: SetPointEditField
        function SetPointEditFieldValueChanged(app, event)
            value = app.SetPointEditField.Value;
            app.device.pid(1).control.set(value);
            app.updateDevice;
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            app.device.pid(1).lower_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.device.pid(2).enable.set(value);
            app.updateDevice;
        end

        % Value changed function: PolaritySwitch_2
        function PolaritySwitch_2ValueChanged(app, event)
            value = app.PolaritySwitch_2.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.device.pid(2).polarity.set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLimitEditField_2
        function UpperLimitEditField_2ValueChanged(app, event)
            value = app.UpperLimitEditField_2.Value;
            app.device.pid(2).upper_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: SetPointEditField_2
        function SetPointEditField_2ValueChanged(app, event)
            value = app.SetPointEditField_2.Value;
            app.device.pid(2).control.set(value);
            app.updateDevice;
        end

        % Value changed function: LowerLimitEditField_2
        function LowerLimitEditField_2ValueChanged(app, event)
            value = app.LowerLimitEditField_2.Value;
            app.device.pid(2).lower_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            value = app.AutoUpdateSwitch.Value;
            if strcmpi(value,'off')
                app.autoUpdate = 0;
            else
                app.autoUpdate = 1;
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.device.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.device.fetch;
            app.updateDisplay;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: AutoFetchDataSwitch
        function AutoFetchDataSwitchValueChanged(app, event)
            value = app.AutoFetchDataSwitch.Value;
            if strcmpi(value,'off')
                if isa(app.int_timer,'timer')
                    stop(app.int_timer);
                    delete(app.int_timer);
                end
            elseif strcmpi(value,'on')
                app.int_timer = timer('ExecutionMode','fixedSpacing','Name','Scan Retrieval timer','Period',app.PlotUpdateTimemsEditField.Value/1e3);
                app.int_timer.ErrorFcn = @(obj,event) delete(obj);
                app.int_timer.TimerFcn = @(~,~) app.updatePlot;
                start(app.int_timer);
            end
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            value = app.KpSpinner.Value;
            app.device.pid(1).Kp.set(value);
            app.updateDevice;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            value = app.KiSpinner.Value;
            app.device.pid(1).Ki.set(value);
            app.updateDevice;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            value = app.KdSpinner.Value;
            app.device.pid(1).Kd.set(value);
            app.updateDevice;
        end

        % Value changed function: DivisorSpinner
        function DivisorSpinnerValueChanged(app, event)
            value = app.DivisorSpinner.Value;
            app.device.pid(1).divisor.set(value);
            app.updateDevice;
        end

        % Value changed function: KpSpinner_2
        function KpSpinner_2ValueChanged(app, event)
            value = app.KpSpinner_2.Value;
            app.device.pid(2).Kp.set(value);
            app.updateDevice;
        end

        % Value changed function: KiSpinner_2
        function KiSpinner_2ValueChanged(app, event)
            value = app.KiSpinner_2.Value;
            app.device.pid(2).Ki.set(value);
            app.updateDevice;
        end

        % Value changed function: KdSpinner_2
        function KdSpinner_2ValueChanged(app, event)
            value = app.KdSpinner_2.Value;
            app.device.pid(2).Kd.set(value);
            app.updateDevice;
        end

        % Value changed function: DivisorSpinner_2
        function DivisorSpinner_2ValueChanged(app, event)
            value = app.DivisorSpinner_2.Value;
            app.device.pid(2).divisor.set(value);
            app.updateDevice;
        end

        % Value changed function: PlotUpdateTimemsEditField
        function PlotUpdateTimemsEditFieldValueChanged(app, event)
            value = app.PlotUpdateTimemsEditField.Value;
            if isa(app.int_timer,'timer') && isvalid(app.int_timer)
                stop(app.int_timer);
                app.int_timer.Period = value/1e3;
                start(app.int_timer);
            end
        end

        % Value changed function: ModulationFreqMHzEditField
        function ModulationFreqMHzEditFieldValueChanged(app, event)
            value = app.ModulationFreqMHzEditField.Value;
            app.device.phase_inc.set(value*1e6);
            app.updateDevice;
        end

        % Value changed function: DemodPhase1degEditField
        function DemodPhase1degEditFieldValueChanged(app, event)
            value = app.DemodPhase1degEditField.Value;
            app.device.phase_offset.set(value);
            app.updateDevice;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            data = load([path,file],'saved_device');
            app.device.loadstruct(data.saved_device);
            app.updateDevice;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saved_device = app.device.struct;
            uisave('saved_device','servo-parameters');
        end

        % Value changed function: OutputScaleEditField
        function OutputScaleEditFieldValueChanged(app, event)
            value = app.OutputScaleEditField.Value;
            app.device.output_scale.set(value);
            app.updateDevice;
        end

        % Value changed function: Log2ofCICshiftSpinner
        function Log2ofCICshiftSpinnerValueChanged(app, event)
            value = app.Log2ofCICshiftSpinner.Value;
            app.device.cic_shift.set(value);
            app.updateDevice;
        end

        % Value changed function: DemodPhase2degEditField
        function DemodPhase2degEditFieldValueChanged(app, event)
            value = app.DemodPhase2degEditField.Value;
            app.device.dds2_phase_offset.set(value);
            app.updateDevice;
        end

        % Value changed function: DC1Spinner
        function DC1SpinnerValueChanged(app, event)
            value = app.DC1Spinner.Value;
            app.device.pwm(1).set(value);
            app.updateDevice;
        end

        % Value changed function: DC1Slider
        function DC1SliderValueChanged(app, event)
            value = app.DC1Slider.Value;
            app.device.pwm(1).set(value);
            app.updateDevice;
        end

        % Value changed function: DC2Spinner
        function DC2SpinnerValueChanged(app, event)
            value = app.DC2Spinner.Value;
            app.device.pwm(2).set(value);
            app.updateDevice;
        end

        % Value changed function: DC2Slider
        function DC2SliderValueChanged(app, event)
            value = app.DC2Slider.Value;
            app.device.pwm(2).set(value);
            app.updateDevice;
        end

        % Value changed function: DC3Spinner
        function DC3SpinnerValueChanged(app, event)
            value = app.DC3Spinner.Value;
            app.device.pwm(3).set(value);
            app.updateDevice;
        end

        % Value changed function: DC3Slider
        function DC3SliderValueChanged(app, event)
            value = app.DC3Slider.Value;
            app.device.pwm(3).set(value);
            app.updateDevice;
        end

        % Value changed function: DCIncrementEditField
        function DCIncrementEditFieldValueChanged(app, event)
            value = app.DCIncrementEditField.Value;
            value = app.device.CONV_PWM*ceil(value/app.device.CONV_PWM);
            app.DCIncrementEditField.Value = value;
            app.DC1Spinner.Step = value;
            app.DC2Spinner.Step = value;
            app.DC3Spinner.Step = value;
        end

        % Value changed function: EnableSwitch_3
        function EnableSwitch_3ValueChanged(app, event)
            value = app.EnableSwitch_3.Value;
            if strcmpi(value,'off')
                app.device.pid(3).enable.set(0);
            else
                app.device.pid(3).enable.set(1);
            end
            app.updateDevice;
        end

        % Value changed function: KiSpinner_3
        function KiSpinner_3ValueChanged(app, event)
            value = app.KiSpinner_3.Value;
            app.device.pid(3).Ki.set(value);
            app.updateDevice;
        end

        % Value changed function: PolaritySwitch_3
        function PolaritySwitch_3ValueChanged(app, event)
            value = app.PolaritySwitch_3.Value;
            if strcmpi(value,'negative')
                app.device.pid(3).polarity.set(0);
            else
                app.device.pid(3).polarity.set(1);
            end
            app.updateDevice;
        end

        % Value changed function: KpSpinner_3
        function KpSpinner_3ValueChanged(app, event)
            value = app.KpSpinner_3.Value;
            app.device.pid(3).Kp.set(value);
            app.updateDevice;
        end

        % Value changed function: KdSpinner_3
        function KdSpinner_3ValueChanged(app, event)
            value = app.KdSpinner_3.Value;
            app.device.pid(3).Kd.set(value);
            app.updateDevice;
        end

        % Value changed function: DivisorSpinner_3
        function DivisorSpinner_3ValueChanged(app, event)
            value = app.DivisorSpinner_3.Value;
            app.device.pid(3).divisor.set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLimitEditField_3
        function UpperLimitEditField_3ValueChanged(app, event)
            value = app.UpperLimitEditField_3.Value;
            app.device.pid(3).upper_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: LowerLimitEditField_3
        function LowerLimitEditField_3ValueChanged(app, event)
            value = app.LowerLimitEditField_3.Value;
            app.device.pid(3).lower_limit.set(value);
            app.updateDevice;
        end

        % Value changed function: SetPointEditField_3
        function SetPointEditField_3ValueChanged(app, event)
            value = app.SetPointEditField_3.Value;
            app.device.pid(3).control.set(value);
            app.updateDevice;
        end

        % Value changed function: FIFO1DropDown
        function FIFO1DropDownValueChanged(app, event)
            value = app.FIFO1DropDown.Value;
            app.device.fifo_route(1).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO2DropDown
        function FIFO2DropDownValueChanged(app, event)
            value = app.FIFO2DropDown.Value;
            app.device.fifo_route(2).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO3DropDown
        function FIFO3DropDownValueChanged(app, event)
            value = app.FIFO3DropDown.Value;
            app.device.fifo_route(3).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO4DropDown
        function FIFO4DropDownValueChanged(app, event)
            value = app.FIFO4DropDown.Value;
            app.device.fifo_route(4).set(app.convert_fifo_route(value));
            app.updateDevice;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IQBiasControlUIFigure = uifigure('Visible', 'off');
            app.IQBiasControlUIFigure.Name = 'IQ Bias Control';
            app.UIAxes = uiaxes(app.IQBiasControlUIFigure);
            title(app.UIAxes, 'Measurements')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AcquisitionSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.Log2CICRateSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinnerLabel.Text = 'Log2(CIC Rate)';
            app.Log2CICRateSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinner.Limits = [8 13];
            app.Log2CICRateSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2CICRateSpinnerValueChanged, true);
            app.Log2CICRateSpinner.Value = 10;
            app.dtsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.dtsEditField.ValueDisplayFormat = '%.2e';
            app.dtsEditField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.BWHzEditFieldLabel.Text = 'BW [Hz]';
            app.BWHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.BWHzEditField.ValueDisplayFormat = '%.2e';
            app.BWHzEditField.Editable = 'off';
            app.ModulationFreqMHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ModulationFreqMHzEditFieldLabel.Text = 'Modulation Freq [MHz]';
            app.ModulationFreqMHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.ModulationFreqMHzEditField.Limits = [0 30];
            app.ModulationFreqMHzEditField.ValueDisplayFormat = '%.2f';
            app.ModulationFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationFreqMHzEditFieldValueChanged, true);
            app.ModulationFreqMHzEditField.Value = 1;
            app.OutputScaleEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.OutputScaleEditFieldLabel.Text = 'Output Scale';
            app.OutputScaleEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.OutputScaleEditField.Limits = [0 1];
            app.OutputScaleEditField.ValueDisplayFormat = '%.3f';
            app.OutputScaleEditField.ValueChangedFcn = createCallbackFcn(app, @OutputScaleEditFieldValueChanged, true);
            app.OutputScaleEditField.Value = 1;
            app.DemodPhase1degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase1degEditFieldLabel.Text = 'Demod. Phase 1 [deg]';
            app.DemodPhase1degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase1degEditField.ValueDisplayFormat = '%.1f';
            app.DemodPhase1degEditField.ValueChangedFcn = createCallbackFcn(app, @DemodPhase1degEditFieldValueChanged, true);
            app.DemodPhase2degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase2degEditFieldLabel.Text = 'Demod. Phase 2 [deg]';
            app.DemodPhase2degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase2degEditField.ValueDisplayFormat = '%.1f';
            app.DemodPhase2degEditField.ValueChangedFcn = createCallbackFcn(app, @DemodPhase2degEditFieldValueChanged, true);
            app.Log2ofCICshiftSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinnerLabel.Text = 'Log2 of CIC shift';
            app.Log2ofCICshiftSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinner.Limits = [-100 100];
            app.Log2ofCICshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2ofCICshiftSpinnerValueChanged, true);
            app.ApplicationSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.ApplicationSettingsPanel.Title = 'Application Settings';
            app.AutoUpdateSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.UploadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.AutoFetchDataSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoFetchDataSwitchLabel.Text = 'Auto-Fetch Data';
            app.AutoFetchDataSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoFetchDataSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoFetchDataSwitchValueChanged, true);
            app.FetchDataButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.PlotUpdateTimemsEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.PlotUpdateTimemsEditFieldLabel.Text = 'Plot Update Time [ms]';
            app.PlotUpdateTimemsEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.PlotUpdateTimemsEditField.Limits = [0 Inf];
            app.PlotUpdateTimemsEditField.ValueDisplayFormat = '%4.2g';
            app.PlotUpdateTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @PlotUpdateTimemsEditFieldValueChanged, true);
            app.PlotUpdateTimemsEditField.Value = 10;
            app.YLim1Label = uilabel(app.ApplicationSettingsPanel);
            app.YLim1Label.Text = 'YLim1';
            app.YLim1EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim1EditField.Value = '-Inf,Inf';
            app.LoadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AcquisitionSamplesEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.AcquisitionSamplesEditFieldLabel.Text = 'Acquisition Samples';
            app.AcquisitionSamplesEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.AcquisitionSamplesEditField.Limits = [0 Inf];
            app.AcquisitionSamplesEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitionSamplesEditField.Value = 1000;
            app.YLim2EditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YLim2EditFieldLabel.Text = 'YLim2';
            app.YLim2EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim2EditField.Value = '-Inf,Inf';
            app.TabGroup2 = uitabgroup(app.IQBiasControlUIFigure);
            app.PID1Tab = uitab(app.TabGroup2);
            app.PID1Tab.Title = 'PID 1';
            app.RealKpEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKpEditField.Editable = 'off';
            app.RealKiEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKiEditField.Editable = 'off';
            app.RealKdEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKdEditField.Editable = 'off';
            app.RealKiLabel = uilabel(app.PID1Tab);
            app.RealKiLabel.Text = '1/s';
            app.ReadKdLabel = uilabel(app.PID1Tab);
            app.ReadKdLabel.Text = 's';
            app.EnableSwitchLabel = uilabel(app.PID1Tab);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.PID1Tab, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.PolaritySwitchLabel = uilabel(app.PID1Tab);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.PolaritySwitch = uiswitch(app.PID1Tab, 'slider');
            app.PolaritySwitch.Items = {'Negative', 'Positive'};
            app.PolaritySwitch.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitchValueChanged, true);
            app.PolaritySwitch.Value = 'Negative';
            app.UpperLimitEditFieldLabel = uilabel(app.PID1Tab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.PID1Tab, 'numeric');
            app.UpperLimitEditField.Limits = [-1 1];
            app.UpperLimitEditField.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 1;
            app.SetPointEditField_2Label = uilabel(app.PID1Tab);
            app.SetPointEditField_2Label.Text = 'Set Point';
            app.SetPointEditField = uieditfield(app.PID1Tab, 'numeric');
            app.SetPointEditField.Limits = [-10000 10000];
            app.SetPointEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointEditFieldValueChanged, true);
            app.LowerLimitEditFieldLabel = uilabel(app.PID1Tab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.PID1Tab, 'numeric');
            app.LowerLimitEditField.Limits = [-1 1];
            app.LowerLimitEditField.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.KpSpinnerLabel = uilabel(app.PID1Tab);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.PID1Tab);
            app.KpSpinner.Limits = [0 255];
            app.KpSpinner.RoundFractionalValues = 'on';
            app.KpSpinner.ValueDisplayFormat = '%d';
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.KiSpinnerLabel = uilabel(app.PID1Tab);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.PID1Tab);
            app.KiSpinner.Limits = [0 255];
            app.KiSpinner.RoundFractionalValues = 'on';
            app.KiSpinner.ValueDisplayFormat = '%d';
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.PID1Tab);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.PID1Tab);
            app.KdSpinner.Limits = [0 255];
            app.KdSpinner.RoundFractionalValues = 'on';
            app.KdSpinner.ValueDisplayFormat = '%d';
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.DivisorSpinnerLabel = uilabel(app.PID1Tab);
            app.DivisorSpinnerLabel.Text = 'Divisor';
            app.DivisorSpinner = uispinner(app.PID1Tab);
            app.DivisorSpinner.Limits = [0 255];
            app.DivisorSpinner.RoundFractionalValues = 'on';
            app.DivisorSpinner.ValueDisplayFormat = '%d';
            app.DivisorSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinnerValueChanged, true);
            app.PID2Tab = uitab(app.TabGroup2);
            app.PID2Tab.Title = 'PID 2';
            app.RealKpEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKpEditField_2.Editable = 'off';
            app.RealKiEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKiEditField_2.Editable = 'off';
            app.RealKdEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKdEditField_2.Editable = 'off';
            app.RealKiLabel_2 = uilabel(app.PID2Tab);
            app.RealKiLabel_2.Text = '1/s';
            app.RealKdLabel_2 = uilabel(app.PID2Tab);
            app.RealKdLabel_2.Text = 's';
            app.EnableSwitch_2Label = uilabel(app.PID2Tab);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.PID2Tab, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.PolaritySwitch_2Label = uilabel(app.PID2Tab);
            app.PolaritySwitch_2Label.Text = 'Polarity';
            app.PolaritySwitch_2 = uiswitch(app.PID2Tab, 'slider');
            app.PolaritySwitch_2.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_2.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitch_2ValueChanged, true);
            app.PolaritySwitch_2.Value = 'Negative';
            app.UpperLimitEditField_2Label = uilabel(app.PID2Tab);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.UpperLimitEditField_2.Limits = [-1 1];
            app.UpperLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditField_2ValueChanged, true);
            app.UpperLimitEditField_2.Value = 1;
            app.SetPointEditField_3Label = uilabel(app.PID2Tab);
            app.SetPointEditField_3Label.Text = 'Set Point';
            app.SetPointEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.SetPointEditField_2.Limits = [-10000 10000];
            app.SetPointEditField_2.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetPointEditField_2ValueChanged, true);
            app.LowerLimitEditField_2Label = uilabel(app.PID2Tab);
            app.LowerLimitEditField_2Label.Text = 'Lower Limit';
            app.LowerLimitEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.LowerLimitEditField_2.Limits = [-1 1];
            app.LowerLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditField_2ValueChanged, true);
            app.KpSpinner_2Label = uilabel(app.PID2Tab);
            app.KpSpinner_2Label.Text = 'Kp';
            app.KpSpinner_2 = uispinner(app.PID2Tab);
            app.KpSpinner_2.Limits = [0 255];
            app.KpSpinner_2.RoundFractionalValues = 'on';
            app.KpSpinner_2.ValueDisplayFormat = '%d';
            app.KpSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KpSpinner_2ValueChanged, true);
            app.KiSpinner_2Label = uilabel(app.PID2Tab);
            app.KiSpinner_2Label.Text = 'Ki';
            app.KiSpinner_2 = uispinner(app.PID2Tab);
            app.KiSpinner_2.Limits = [0 255];
            app.KiSpinner_2.RoundFractionalValues = 'on';
            app.KiSpinner_2.ValueDisplayFormat = '%d';
            app.KiSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KiSpinner_2ValueChanged, true);
            app.KdSpinner_2Label = uilabel(app.PID2Tab);
            app.KdSpinner_2Label.Text = 'Kd';
            app.KdSpinner_2 = uispinner(app.PID2Tab);
            app.KdSpinner_2.Limits = [0 255];
            app.KdSpinner_2.RoundFractionalValues = 'on';
            app.KdSpinner_2.ValueDisplayFormat = '%d';
            app.KdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KdSpinner_2ValueChanged, true);
            app.DivisorSpinner_2Label = uilabel(app.PID2Tab);
            app.DivisorSpinner_2Label.Text = 'Divisor';
            app.DivisorSpinner_2 = uispinner(app.PID2Tab);
            app.DivisorSpinner_2.Limits = [0 255];
            app.DivisorSpinner_2.RoundFractionalValues = 'on';
            app.DivisorSpinner_2.ValueDisplayFormat = '%d';
            app.DivisorSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinner_2ValueChanged, true);
            app.PID3Tab = uitab(app.TabGroup2);
            app.PID3Tab.Title = 'PID 3';
            app.RealKpEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKpEditField_3.Editable = 'off';
            app.RealKiEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKiEditField_3.Editable = 'off';
            app.RealKdEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKdEditField_3.Editable = 'off';
            app.RealKiLabel_3 = uilabel(app.PID3Tab);
            app.RealKiLabel_3.Text = '1/s';
            app.RealKdLabel_3 = uilabel(app.PID3Tab);
            app.RealKdLabel_3.Text = 's';
            app.EnableSwitch_3Label = uilabel(app.PID3Tab);
            app.EnableSwitch_3Label.Text = 'Enable';
            app.EnableSwitch_3 = uiswitch(app.PID3Tab, 'slider');
            app.EnableSwitch_3.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_3ValueChanged, true);
            app.PolaritySwitch_3Label = uilabel(app.PID3Tab);
            app.PolaritySwitch_3Label.Text = 'Polarity';
            app.PolaritySwitch_3 = uiswitch(app.PID3Tab, 'slider');
            app.PolaritySwitch_3.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_3.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitch_3ValueChanged, true);
            app.PolaritySwitch_3.Value = 'Negative';
            app.UpperLimitEditField_3Label = uilabel(app.PID3Tab);
            app.UpperLimitEditField_3Label.Text = 'Upper Limit';
            app.UpperLimitEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.UpperLimitEditField_3.Limits = [0 1.6];
            app.UpperLimitEditField_3.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField_3.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditField_3ValueChanged, true);
            app.UpperLimitEditField_3.Value = 1;
            app.SetPointEditField_3Label_2 = uilabel(app.PID3Tab);
            app.SetPointEditField_3Label_2.Text = 'Set Point';
            app.SetPointEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.SetPointEditField_3.Limits = [-10000 10000];
            app.SetPointEditField_3.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField_3.ValueChangedFcn = createCallbackFcn(app, @SetPointEditField_3ValueChanged, true);
            app.LowerLimitEditField_3Label = uilabel(app.PID3Tab);
            app.LowerLimitEditField_3Label.Text = 'Lower Limit';
            app.LowerLimitEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.LowerLimitEditField_3.Limits = [0 1.6];
            app.LowerLimitEditField_3.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_3.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditField_3ValueChanged, true);
            app.KpSpinner_3Label = uilabel(app.PID3Tab);
            app.KpSpinner_3Label.Text = 'Kp';
            app.KpSpinner_3 = uispinner(app.PID3Tab);
            app.KpSpinner_3.Limits = [0 255];
            app.KpSpinner_3.RoundFractionalValues = 'on';
            app.KpSpinner_3.ValueDisplayFormat = '%d';
            app.KpSpinner_3.ValueChangedFcn = createCallbackFcn(app, @KpSpinner_3ValueChanged, true);
            app.KiSpinner_3Label = uilabel(app.PID3Tab);
            app.KiSpinner_3Label.Text = 'Ki';
            app.KiSpinner_3 = uispinner(app.PID3Tab);
            app.KiSpinner_3.Limits = [0 255];
            app.KiSpinner_3.RoundFractionalValues = 'on';
            app.KiSpinner_3.ValueDisplayFormat = '%d';
            app.KiSpinner_3.ValueChangedFcn = createCallbackFcn(app, @KiSpinner_3ValueChanged, true);
            app.KdSpinner_3Label = uilabel(app.PID3Tab);
            app.KdSpinner_3Label.Text = 'Kd';
            app.KdSpinner_3 = uispinner(app.PID3Tab);
            app.KdSpinner_3.Limits = [0 255];
            app.KdSpinner_3.RoundFractionalValues = 'on';
            app.KdSpinner_3.ValueDisplayFormat = '%d';
            app.KdSpinner_3.ValueChangedFcn = createCallbackFcn(app, @KdSpinner_3ValueChanged, true);
            app.DivisorSpinner_3Label = uilabel(app.PID3Tab);
            app.DivisorSpinner_3Label.Text = 'Divisor';
            app.DivisorSpinner_3 = uispinner(app.PID3Tab);
            app.DivisorSpinner_3.Limits = [0 255];
            app.DivisorSpinner_3.RoundFractionalValues = 'on';
            app.DivisorSpinner_3.ValueDisplayFormat = '%d';
            app.DivisorSpinner_3.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinner_3ValueChanged, true);
            app.ManualControlPanel = uipanel(app.IQBiasControlUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.SliderLabel = uilabel(app.ManualControlPanel);
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.DC1Slider = uislider(app.ManualControlPanel);
            app.DC1Slider.Limits = [0 1];
            app.DC1Slider.ValueChangedFcn = createCallbackFcn(app, @DC1SliderValueChanged, true);
            app.DC1SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC1SpinnerLabel.Text = 'DC1';
            app.DC1Spinner = uispinner(app.ManualControlPanel);
            app.DC1Spinner.Step = 0.01;
            app.DC1Spinner.Limits = [0 1];
            app.DC1Spinner.ValueChangedFcn = createCallbackFcn(app, @DC1SpinnerValueChanged, true);
            app.SliderLabel_2 = uilabel(app.ManualControlPanel);
            app.SliderLabel_2.Visible = 'off';
            app.SliderLabel_2.Text = 'Slider';
            app.DC2Slider = uislider(app.ManualControlPanel);
            app.DC2Slider.Limits = [0 1];
            app.DC2Slider.ValueChangedFcn = createCallbackFcn(app, @DC2SliderValueChanged, true);
            app.DC2SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC2SpinnerLabel.Text = 'DC2';
            app.DC2Spinner = uispinner(app.ManualControlPanel);
            app.DC2Spinner.Step = 0.01;
            app.DC2Spinner.Limits = [0 1];
            app.DC2Spinner.ValueChangedFcn = createCallbackFcn(app, @DC2SpinnerValueChanged, true);
            app.SliderLabel_3 = uilabel(app.ManualControlPanel);
            app.SliderLabel_3.Visible = 'off';
            app.SliderLabel_3.Text = 'Slider';
            app.DC3Slider = uislider(app.ManualControlPanel);
            app.DC3Slider.Limits = [0 1];
            app.DC3Slider.ValueChangedFcn = createCallbackFcn(app, @DC3SliderValueChanged, true);
            app.DC3SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC3SpinnerLabel.Text = 'DC3';
            app.DC3Spinner = uispinner(app.ManualControlPanel);
            app.DC3Spinner.Step = 0.01;
            app.DC3Spinner.Limits = [0 1];
            app.DC3Spinner.ValueChangedFcn = createCallbackFcn(app, @DC3SpinnerValueChanged, true);
            app.DCIncrementEditFieldLabel = uilabel(app.ManualControlPanel);
            app.DCIncrementEditFieldLabel.Text = 'DC Increment';
            app.DCIncrementEditField = uieditfield(app.ManualControlPanel, 'numeric');
            app.DCIncrementEditField.Limits = [0.001 1];
            app.DCIncrementEditField.ValueDisplayFormat = '%.3f';
            app.DCIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @DCIncrementEditFieldValueChanged, true);
            app.DCIncrementEditField.Value = 0.01;
            app.FIFOSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.FIFOSettingsPanel.Title = 'FIFO Settings';
            app.FIFO1DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO1DropDownLabel.Text = 'FIFO 1';
            app.FIFO1DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO1DropDown.Items = {'In', 'Out'};
            app.FIFO1DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO1DropDownValueChanged, true);
            app.FIFO1DropDown.Value = 'In';
            app.FIFO2DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO2DropDownLabel.Text = 'FIFO 2';
            app.FIFO2DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO2DropDown.Items = {'In', 'Out'};
            app.FIFO2DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO2DropDownValueChanged, true);
            app.FIFO2DropDown.Value = 'In';
            app.FIFO3DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO3DropDownLabel.Text = 'FIFO 3';
            app.FIFO3DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO3DropDown.Items = {'In', 'Out'};
            app.FIFO3DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO3DropDownValueChanged, true);
            app.FIFO3DropDown.Value = 'In';
            app.FIFO4DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO4DropDownLabel.Text = 'FIFO 4';
            app.FIFO4DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO4DropDown.Items = {'In', 'Out'};
            app.FIFO4DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO4DropDownValueChanged, true);
            app.FIFO4DropDown.Value = 'In';
            app.FIFO1CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO1CheckBox.Text = 'FIFO1';
            app.FIFO1CheckBox.Value = true;
            app.FIFO2CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO2CheckBox.Text = 'FIFO2';
            app.FIFO2CheckBox.Value = true;
            app.FIFO3CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO3CheckBox.Text = 'FIFO3';
            app.FIFO3CheckBox.Value = true;
            app.FIFO4CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO4CheckBox.Text = 'FIFO4';
            app.FIFO4CheckBox.Value = true;
            % Show the figure after all components are created
            app.IQBiasControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IQ_Bias_Control_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IQBiasControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IQBiasControlUIFigure)
        end
    end
end